classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OceanShipDetectionSystemUIFigure  matlab.ui.Figure
        OpenMenu                      matlab.ui.container.Menu
        OpenImageMenu                 matlab.ui.container.Menu
        OpenFolderMenu                matlab.ui.container.Menu
        DescriptionTextAreaLabel      matlab.ui.control.Label
        DescriptionTextArea           matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
        Button                        matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes2_2                     matlab.ui.control.UIAxes
        Panel                         matlab.ui.container.Panel
        PreProcessPanel               matlab.ui.container.Panel
        DenoisePanel                  matlab.ui.container.Panel
        WindowSliderLabel             matlab.ui.control.Label
        WindowSlider                  matlab.ui.control.Slider
        WaveletCompressPanel          matlab.ui.container.Panel
        StepsSliderLabel              matlab.ui.control.Label
        StepsSlider                   matlab.ui.control.Slider
        MotionClearWinnerFilterPanel  matlab.ui.container.Panel
        MotionLenSliderLabel          matlab.ui.control.Label
        MotionLenSlider               matlab.ui.control.Slider
        MotionThetaSliderLabel        matlab.ui.control.Label
        MotionThetaSlider             matlab.ui.control.Slider
        NoiseValueSliderLabel         matlab.ui.control.Label
        NoiseValueSlider              matlab.ui.control.Slider
        ImageEnhancementPanel         matlab.ui.container.Panel
        DCTPanel                      matlab.ui.container.Panel
        BlockSizeSliderLabel          matlab.ui.control.Label
        BlockSizeSlider               matlab.ui.control.Slider
        ColorEnhancementPanel         matlab.ui.container.Panel
        OpenCloseCheckBox             matlab.ui.control.CheckBox
        ShipDetectionPanel            matlab.ui.container.Panel
        PretrainedModelPathEditFieldLabel  matlab.ui.control.Label
        PretrainedModelPathEditField  matlab.ui.control.EditField
        LoadPretrainedModelButton     matlab.ui.control.Button
        ClickdirectlyifdontknowmodelpathLabel  matlab.ui.control.Label
        ImgFolderEditFieldLabel       matlab.ui.control.Label
        ImgFolderEditField            matlab.ui.control.EditField
        MotionLabel                   matlab.ui.control.Label
        MotionSwitch                  matlab.ui.control.Switch
        DenoiseLabel                  matlab.ui.control.Label
        DenoiseSwitch                 matlab.ui.control.Switch
        CompressLabel                 matlab.ui.control.Label
        CompressSwitch                matlab.ui.control.Switch
        DetectionButton               matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        DCTSwitchLabel                matlab.ui.control.Label
        DCTSwitch                     matlab.ui.control.Switch
        ColorSwitchLabel              matlab.ui.control.Label
        ColorSwitch                   matlab.ui.control.Switch
        SaveButton                    matlab.ui.control.Button
        Copyrightawiny2018dspprojectEmailawinyimgprocessgmailcomLabel  matlab.ui.control.Label
        NextImageAutoDetectionButton  matlab.ui.control.Button
    end


    properties (Access = public)
        img_file_path = "" % Description
        directory_name = ""
        index = 1 %used for open folder
        transform_img = ""
        temp_img = ""
        detection_img = ""
        img_dir = ""
        motion_len = 2
        motion_theta = 0
        noise_value = 0.1
        middle_filter_window = 3
        wavelet_compress_steps = 8
        dct_block_size = 8
        color_open = 0
        data = ""
        detector = ""
        Denoise = false
        Motion = false
        Compress = false
        DCT = false
        Color = false
    end

    methods (Access = public)
    
        function [] = read_image(app)
            app.transform_img = (imread(app.img_file_path));
            app.temp_img = (imread(app.img_file_path));
        end
        
        function [] = show_image(app)
            imshow(app.temp_img, 'parent', app.UIAxes2);
        end
        function [] = show_wiener_filter_result(app)
            if app.Motion == true
                app.temp_img = wiener_filter(app.transform_img, round(app.motion_len), app.motion_theta, app.noise_value);
                imshow(app.temp_img, 'parent', app.UIAxes2);
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Open Winner Filter Button!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Open Winner Filter Button")
            end
        end
        function [] = load_pretrained_model(app, file_path)
            if file_path ~= ""
                f = app.OceanShipDetectionSystemUIFigure;
                d = uiprogressdlg(f,'Title','Please Wait',...
                    'Message','Loading The File...');
                pause(.5)
                app.data = load(file_path);
                % Perform calculations
                % ...
                d.Value = .99; 
                d.Message = 'Loading end!';
                pause(1)
            else
                f = app.OceanShipDetectionSystemUIFigure;
                d = uiprogressdlg(f,'Title','Please Wait',...
                    'Message','Loading The File...');
                pause(.5)
                app.data = load('ship_detection.mat');
                % Perform calculations
                % ...
                d.Value = .99; 
                d.Message = 'Loading end!';
                pause(1)
            end
            app.detector = app.data.data.detector;
             fig = app.OceanShipDetectionSystemUIFigure;
            message = sprintf('Loaded Done!');
            uialert(fig,message,'success',...
            'Icon','success');
        end
        function [] = show_ship_detecion_result(app)           
            if app.detector ~= ""
                f = app.OceanShipDetectionSystemUIFigure;
                d = uiprogressdlg(f,'Title','Please Wait',...
                    'Message','Loading And Detection');
                pause(.5)
                app.detection_img = ship_detection(app.transform_img, app.detector)              
                % Perform calculations
                % ...
                d.Value = .99; 
                d.Message = 'Detection Finished!';
                pause(1)
                imshow(app.detection_img, 'parent', app.UIAxes2_2);
            
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Please Open Pretrained Module First!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Please Open Pretrained Module First!")
            end
        end
        function [] = show_middle_filter_img(app)
            if app.Denoise == true
                app.temp_img = middle_filter(app.transform_img, app.middle_filter_window);
                imshow(app.temp_img, 'parent', app.UIAxes2);
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Open Middle Filter Button!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Open Middle Filter Button")
            end
        end
        function [] = show_wavelet_compress_result(app)
            if app.Compress == true
                app.temp_img = wavelet_compress(app.transform_img, app.wavelet_compress_steps);
                imshow(app.temp_img, 'parent', app.UIAxes2);
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Open Compress Button!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Open Compress Button")
            end
            
        end
        function [] = show_dct_result(app)
            if app.DCT == true
                try
                    app.temp_img = dct_transform(app.transform_img, app.dct_block_size);
                    imshow(app.temp_img, 'parent', app.UIAxes2);
                catch exception
                    rethrow(exception)
                end
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Open DCT Button!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Open DCT Button")
            end
        end
        function [] = show_color_result(app)
            if app.Color == true
                app.temp_img = autoEnhancement(app.transform_img);
                imshow(app.temp_img, 'parent', app.UIAxes2);
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Open Color Button!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Open Color Button")
            end
        end
        function [status] = catch_expection(app)
            try
                if app.transform_img == "" || app.temp_img == ""
                    fig = app.OceanShipDetectionSystemUIFigure;
                    message = sprintf('Open an Image first!');
                    uialert(fig,message,'error',...
                    'Icon','error');
                    status = -1
                else
                    status = 1
                end
            catch
                status = 1
                fprintf("have image!")
            end
        end
        
    end


    methods (Access = private)

        % Callback function
        function nsrSliderValueChanged(app, event)
            value = app.nsrSlider.Value;
        end

        % Callback function
        function OpenImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp*'},...
                        'Select an Image File')
            app.img_file_path = strcat(path, file);
            imshow(app.img_file_path, 'parent', app.UIAxes);
            %app.transform_img = imread(app.img_file_path);
            %app.temp_img = imread(app.img_file_path);
            read_img(app);
            %show_wiener_filter_result(app);
        end

        % Value changed function: MotionLenSlider
        function MotionLenSliderValueChanged(app, event)
            if app.Motion == true
                app.motion_len = app.MotionLenSlider.Value;
                show_wiener_filter_result(app);
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Open Motion Button!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Open Motion Button")
            end
        end

        % Value changed function: MotionThetaSlider
        function MotionThetaSliderValueChanged(app, event)
            if app.Motion == true
                app.motion_theta = app.MotionThetaSlider.Value;
                show_wiener_filter_result(app);
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Open Motion Button!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Open Motion Button")
            end
        end

        % Value changed function: NoiseValueSlider
        function NoiseValueSliderValueChanged(app, event)
            if app.Motion == true
                app.noise_value = app.NoiseValueSlider.Value;
                show_wiener_filter_result(app);
            else
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Open Motion Button!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Open Motion Button")
            end
        end

        % Menu selected function: OpenImageMenu
        function openimage(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp*'},...
                        'Select an Image File')
            if file == 0
                fig = app.OceanShipDetectionSystemUIFigure;
                message = sprintf('Please Choose One Image!');
                uialert(fig,message,'Warning',...
                'Icon','warning');
                fprintf("Please Choose One Image")
            else
                app.img_file_path = strcat(path, file);
                imshow(app.img_file_path, 'parent', app.UIAxes);
                read_image(app);
            end
            %show_wiener_filter_result(app);
        end

        % Value changing function: WindowSlider
        function WindowSliderValueChanging(app, event)
            if mod(floor(event.Value), 2) == 1
                app.middle_filter_window = floor(event.Value);
            else
                app.middle_filter_window = floor(event.Value) + 1;
            end
            show_middle_filter_img(app);
        end

        % Button pushed function: DetectionButton
        function ShipDetection(app, event)
            status = catch_expection(app)
            if status > -1
                show_ship_detecion_result(app);
            else
                fprintf("Something Error")
            end
        end

        % Button pushed function: LoadPretrainedModelButton
        function LoadPretrainedModel(app, event)
            if app.PretrainedModelPathEditField.Value ~= ""
                load_pretrained_model(app, app.PretrainedModelPathEditField.Value);
            else
                load_pretrained_model(app, "");
            end
        end

        % Value changed function: DenoiseSwitch
        function DenoiseChanged(app, event)
            value = app.DenoiseSwitch.Value;
            if string(value) == 'On'
                app.Denoise = true
            else
                app.Denoise = false
            end
        end

        % Value changed function: MotionSwitch
        function MotionChanged(app, event)
            value = app.MotionSwitch.Value;
            if value == 'On'
                app.Motion = true
            else
                app.Motion = false
            end            
        end

        % Value changed function: CompressSwitch
        function CompressChanged(app, event)
            value = app.CompressSwitch.Value;
            if string(value) == 'On'
                app.Compress = true
            else
                app.Compress = false
            end
        end

        % Value changed function: DCTSwitch
        function DCTChanged(app, event)
            value = app.DCTSwitch.Value;
            if string(value) == 'On'
                app.DCT = true
            else
                app.DCT = false
            end
        end

        % Value changed function: ColorSwitch
        function ColorChanged(app, event)
            value = app.ColorSwitch.Value;
            if string(value) == 'On'
                app.Color = true
            else
                app.Color = false
            end
        end

        % Value changed function: StepsSlider
        function WaveletChanged(app, event)
            app.wavelet_compress_steps = ceil(app.StepsSlider.Value);
            show_wavelet_compress_result(app);
        end

        % Button pushed function: SaveButton
        function SaveImage(app, event)
            app.transform_img = app.temp_img;
            show_image(app);
        end

        % Button pushed function: ResetButton
        function ResetClick(app, event)
            app.temp_img = app.transform_img;
            show_image(app);
        end

        % Value changed function: BlockSizeSlider
        function BlockSizeSliderValueChanged(app, event)
            app.dct_block_size = ceil(app.BlockSizeSlider.Value);
            show_dct_result(app);
        end

        % Value changed function: OpenCloseCheckBox
        function OpenCloseCheckBoxValueChanged(app, event)
            app.color_open = app.OpenCloseCheckBox.Value;
            if app.color_open == 1
                show_color_result(app);
            else
                imshow(app.transform_img, 'parent', app.UIAxes2);
                fprintf("Close")
            end
        end

        % Menu selected function: OpenFolderMenu
        function OpenFolderMenuSelected(app, event)
            app.directory_name = uigetdir
            app.ImgFolderEditField.Value = string(app.directory_name) + '/'
            app.img_dir = dir([app.ImgFolderEditField.Value '*.png'])
        end

        % Button pushed function: NextImageAutoDetectionButton
        function NextImageAutoDetectionButtonPushed(app, event)

            img = imread([app.ImgFolderEditField.Value app.img_dir(app.index).name]);
            app.temp_img = img;
            imshow(img, 'parent', app.UIAxes);
            app.transform_img = app.temp_img;
            app.show_ship_detecion_result();
            app.index = app.index + 1;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OceanShipDetectionSystemUIFigure = uifigure;
            app.OceanShipDetectionSystemUIFigure.Name = 'Ocean Ship Detection System';
            app.OpenMenu = uimenu(app.OceanShipDetectionSystemUIFigure);
            app.OpenMenu.Text = 'Open';
            app.OpenImageMenu = uimenu(app.OpenMenu);
            app.OpenImageMenu.MenuSelectedFcn = createCallbackFcn(app, @openimage, true);
            app.OpenImageMenu.Text = 'Open Image';
            app.OpenFolderMenu = uimenu(app.OpenMenu);
            app.OpenFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFolderMenuSelected, true);
            app.OpenFolderMenu.Text = 'Open Folder';
            app.DescriptionTextAreaLabel = uilabel(app.OceanShipDetectionSystemUIFigure);
            app.DescriptionTextAreaLabel.Enable = 'off';
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.OceanShipDetectionSystemUIFigure);
            app.DescriptionTextArea.Editable = 'off';
            app.DescriptionTextArea.Enable = 'off';
            app.DescriptionTextArea.Value = {'RUN:'; 'Step1: "Open" Image On Upperleft.'; 'Step2: Open OFF/ON Button selectivity.'; 'Step3: Change value.(Save it if finised preprocess and image enhancement).'; 'Step4: Load pretrained model and press "detection".'; 'Preprocee: Denoise: Med Filter Motion Clear: Wineer Filter Image Clear: Wavelet Transform'; 'Image Enhancement: DCT, Color Enhancement'; 'Detection: mlp based faster-rcnn and resnet50 based faster-rcnn'};
            app.UIAxes = uiaxes(app.OceanShipDetectionSystemUIFigure);
            title(app.UIAxes, 'Origin Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.Button = uibutton(app.OceanShipDetectionSystemUIFigure, 'push');
            app.Button.Icon = 'ico.png';
            app.Button.Text = '';
            app.UIAxes2 = uiaxes(app.OceanShipDetectionSystemUIFigure);
            title(app.UIAxes2, 'Changed Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.OceanShipDetectionSystemUIFigure);
            title(app.UIAxes2_2, 'Detecetion Result')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.XTickLabel = '';
            app.UIAxes2_2.YTick = [];
            app.Panel = uipanel(app.OceanShipDetectionSystemUIFigure);
            app.PreProcessPanel = uipanel(app.Panel);
            app.PreProcessPanel.Title = 'PreProcess';
            app.DenoisePanel = uipanel(app.PreProcessPanel);
            app.DenoisePanel.Title = 'Denoise';
            app.WindowSliderLabel = uilabel(app.DenoisePanel);
            app.WindowSliderLabel.Text = 'Window';
            app.WindowSlider = uislider(app.DenoisePanel);
            app.WindowSlider.Limits = [3 11];
            app.WindowSlider.MajorTicks = [3 5 7 9 11];
            app.WindowSlider.ValueChangingFcn = createCallbackFcn(app, @WindowSliderValueChanging, true);
            app.WindowSlider.MinorTicks = [];
            app.WindowSlider.Value = 3;
            app.WaveletCompressPanel = uipanel(app.PreProcessPanel);
            app.WaveletCompressPanel.Title = 'Wavelet Compress';
            app.StepsSliderLabel = uilabel(app.WaveletCompressPanel);
            app.StepsSliderLabel.Text = 'Steps';
            app.StepsSlider = uislider(app.WaveletCompressPanel);
            app.StepsSlider.Limits = [1 11];
            app.StepsSlider.ValueChangedFcn = createCallbackFcn(app, @WaveletChanged, true);
            app.StepsSlider.Value = 8;
            app.MotionClearWinnerFilterPanel = uipanel(app.PreProcessPanel);
            app.MotionClearWinnerFilterPanel.Title = 'Motion Clear Winner Filter';
            app.MotionLenSliderLabel = uilabel(app.MotionClearWinnerFilterPanel);
            app.MotionLenSliderLabel.Text = 'Motion Len';
            app.MotionLenSlider = uislider(app.MotionClearWinnerFilterPanel);
            app.MotionLenSlider.Limits = [2 100];
            app.MotionLenSlider.ValueChangedFcn = createCallbackFcn(app, @MotionLenSliderValueChanged, true);
            app.MotionLenSlider.MinorTicks = [];
            app.MotionLenSlider.Value = 2;
            app.MotionThetaSliderLabel = uilabel(app.MotionClearWinnerFilterPanel);
            app.MotionThetaSliderLabel.Text = 'Motion Theta';
            app.MotionThetaSlider = uislider(app.MotionClearWinnerFilterPanel);
            app.MotionThetaSlider.Limits = [0 360];
            app.MotionThetaSlider.ValueChangedFcn = createCallbackFcn(app, @MotionThetaSliderValueChanged, true);
            app.MotionThetaSlider.MinorTicks = [];
            app.NoiseValueSliderLabel = uilabel(app.MotionClearWinnerFilterPanel);
            app.NoiseValueSliderLabel.Text = 'Noise Value';
            app.NoiseValueSlider = uislider(app.MotionClearWinnerFilterPanel);
            app.NoiseValueSlider.Limits = [0 1];
            app.NoiseValueSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseValueSliderValueChanged, true);
            app.NoiseValueSlider.MinorTicks = [];
            app.NoiseValueSlider.Value = 0.1;
            app.ImageEnhancementPanel = uipanel(app.Panel);
            app.ImageEnhancementPanel.Title = 'Image Enhancement';
            app.DCTPanel = uipanel(app.ImageEnhancementPanel);
            app.DCTPanel.Title = 'DCT';
            app.BlockSizeSliderLabel = uilabel(app.DCTPanel);
            app.BlockSizeSliderLabel.Text = 'BlockSize';
            app.BlockSizeSlider = uislider(app.DCTPanel);
            app.BlockSizeSlider.Limits = [4 12];
            app.BlockSizeSlider.ValueChangedFcn = createCallbackFcn(app, @BlockSizeSliderValueChanged, true);
            app.BlockSizeSlider.Value = 4;
            app.ColorEnhancementPanel = uipanel(app.ImageEnhancementPanel);
            app.ColorEnhancementPanel.Title = 'Color Enhancement';
            app.OpenCloseCheckBox = uicheckbox(app.ColorEnhancementPanel);
            app.OpenCloseCheckBox.ValueChangedFcn = createCallbackFcn(app, @OpenCloseCheckBoxValueChanged, true);
            app.OpenCloseCheckBox.Text = 'Open/Close';
            app.ShipDetectionPanel = uipanel(app.Panel);
            app.ShipDetectionPanel.Title = 'Ship Detection';
            app.PretrainedModelPathEditFieldLabel = uilabel(app.ShipDetectionPanel);
            app.PretrainedModelPathEditFieldLabel.Text = 'Pretrained Model Path';
            app.PretrainedModelPathEditField = uieditfield(app.ShipDetectionPanel, 'text');
            app.LoadPretrainedModelButton = uibutton(app.ShipDetectionPanel, 'push');
            app.LoadPretrainedModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPretrainedModel, true);
            app.LoadPretrainedModelButton.Text = 'Load  Pretrained Model';
            app.ClickdirectlyifdontknowmodelpathLabel = uilabel(app.ShipDetectionPanel);
            app.ClickdirectlyifdontknowmodelpathLabel.Text = 'Click directly if don''t know model path.';
            app.ImgFolderEditFieldLabel = uilabel(app.ShipDetectionPanel);
            app.ImgFolderEditFieldLabel.Text = 'Img Folder';
            app.ImgFolderEditField = uieditfield(app.ShipDetectionPanel, 'text');
            app.MotionLabel = uilabel(app.OceanShipDetectionSystemUIFigure);
            app.MotionLabel.Text = 'Motion:';
            app.MotionSwitch = uiswitch(app.OceanShipDetectionSystemUIFigure, 'slider');
            app.MotionSwitch.ValueChangedFcn = createCallbackFcn(app, @MotionChanged, true);
            app.DenoiseLabel = uilabel(app.OceanShipDetectionSystemUIFigure);
            app.DenoiseLabel.Text = 'Denoise:';
            app.DenoiseSwitch = uiswitch(app.OceanShipDetectionSystemUIFigure, 'slider');
            app.DenoiseSwitch.ValueChangedFcn = createCallbackFcn(app, @DenoiseChanged, true);
            app.CompressLabel = uilabel(app.OceanShipDetectionSystemUIFigure);
            app.CompressLabel.Text = 'Compress:';
            app.CompressSwitch = uiswitch(app.OceanShipDetectionSystemUIFigure, 'slider');
            app.CompressSwitch.ValueChangedFcn = createCallbackFcn(app, @CompressChanged, true);
            app.DetectionButton = uibutton(app.OceanShipDetectionSystemUIFigure, 'push');
            app.DetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ShipDetection, true);
            app.DetectionButton.Text = 'Detection';
            app.ResetButton = uibutton(app.OceanShipDetectionSystemUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetClick, true);
            app.ResetButton.Text = 'Reset';
            app.DCTSwitchLabel = uilabel(app.OceanShipDetectionSystemUIFigure);
            app.DCTSwitchLabel.Text = 'DCT';
            app.DCTSwitch = uiswitch(app.OceanShipDetectionSystemUIFigure, 'slider');
            app.DCTSwitch.ValueChangedFcn = createCallbackFcn(app, @DCTChanged, true);
            app.ColorSwitchLabel = uilabel(app.OceanShipDetectionSystemUIFigure);
            app.ColorSwitchLabel.Text = 'Color';
            app.ColorSwitch = uiswitch(app.OceanShipDetectionSystemUIFigure, 'slider');
            app.ColorSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorChanged, true);
            app.SaveButton = uibutton(app.OceanShipDetectionSystemUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImage, true);
            app.SaveButton.Text = 'Save';
            app.Copyrightawiny2018dspprojectEmailawinyimgprocessgmailcomLabel = uilabel(app.OceanShipDetectionSystemUIFigure);
            app.Copyrightawiny2018dspprojectEmailawinyimgprocessgmailcomLabel.Text = 'Copyright: awiny@2018 dsp project Email:awinyimgprocess@gmail.com';
            app.NextImageAutoDetectionButton = uibutton(app.OceanShipDetectionSystemUIFigure, 'push');
            app.NextImageAutoDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageAutoDetectionButtonPushed, true);
            app.NextImageAutoDetectionButton.Text = 'NextImage(Auto Detection)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OceanShipDetectionSystemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OceanShipDetectionSystemUIFigure)
        end
    end
end